classdef dialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        OKButton            matlab.ui.control.Button
        EditField           matlab.ui.control.EditField
        EnterfilenameLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % main app object
        caseToSave % what the user want to save
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, casetosave)
            % Store main app object
            app.CallingApp = mainapp;
            app.caseToSave = casetosave;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            name = app.EditField.Value;
            
            switch app.caseToSave
                case 1
                    type = '.png';
                    fileName = strcat(name,type);
                    savedotmatrix(app.CallingApp,fileName);
                case 2
                    savealignment(app.CallingApp,name);
                case 3
                    savescorematrix(app.CallingApp,name);
                case 4
                    savelocalalignment(app.CallingApp,name)
                
            end
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.CallingApp.SavetopngButton.Enable = 'on';
            app.CallingApp.SavealignmenttotxtButton.Enable = 'on';
            app.CallingApp.SavescorematrixtopngButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EnterfilenameLabel = uilabel(app.UIFigure);
            app.EnterfilenameLabel.Text = 'Enter file name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end