classdef seqAnalyzerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        GetdataButton                 matlab.ui.control.Button
        FromncbibaseCheckBox          matlab.ui.control.CheckBox
        FromfileCheckBox              matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        SelectseqsfromtabletoanalyzeLabel  matlab.ui.control.Label
        CleardataButton               matlab.ui.control.Button
        NucleotideCheckBox            matlab.ui.control.CheckBox
        ProteinCheckBox               matlab.ui.control.CheckBox
        ManuallyCheckBox              matlab.ui.control.CheckBox
        TabGroup2                     matlab.ui.container.TabGroup
        SmithWatermanTab              matlab.ui.container.Tab
        SubmitButtonSW                matlab.ui.control.Button
        GapEditField_2Label           matlab.ui.control.Label
        GapEditFieldSW                matlab.ui.control.NumericEditField
        SavealignmenttotxtButtonSW    matlab.ui.control.Button
        ScoreValueSW                  matlab.ui.control.Label
        AlignmentResultSW             matlab.ui.control.Label
        ACGTTextAreaLabel_2           matlab.ui.control.Label
        AEditFieldLabel               matlab.ui.control.Label
        AA                            matlab.ui.control.NumericEditField
        CEditFieldLabel               matlab.ui.control.Label
        CA                            matlab.ui.control.NumericEditField
        GEditFieldLabel               matlab.ui.control.Label
        GA                            matlab.ui.control.NumericEditField
        TEditFieldLabel               matlab.ui.control.Label
        TA                            matlab.ui.control.NumericEditField
        CC                            matlab.ui.control.NumericEditField
        GC                            matlab.ui.control.NumericEditField
        TC                            matlab.ui.control.NumericEditField
        TG                            matlab.ui.control.NumericEditField
        GG                            matlab.ui.control.NumericEditField
        TT                            matlab.ui.control.NumericEditField
        ACGTTextAreaLabel_3           matlab.ui.control.Label
        ACGTTextAreaLabel_4           matlab.ui.control.Label
        ACGTTextAreaLabel_5           matlab.ui.control.Label
        SavescorematrixtopngButtonSW  matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        NeedlemanWunschTab            matlab.ui.container.Tab
        ModeButtonGroup               matlab.ui.container.ButtonGroup
        SimilarityButton              matlab.ui.control.RadioButton
        DistanceButton                matlab.ui.control.RadioButton
        MatchEditFieldLabel           matlab.ui.control.Label
        MatchEditField                matlab.ui.control.NumericEditField
        MismatchEditFieldLabel        matlab.ui.control.Label
        MismatchEditField             matlab.ui.control.NumericEditField
        GapEditFieldLabel             matlab.ui.control.Label
        GapEditField                  matlab.ui.control.NumericEditField
        SubmitButton                  matlab.ui.control.Button
        SavealignmenttotxtButton      matlab.ui.control.Button
        AlignmentResult               matlab.ui.control.Label
        ScoreValue                    matlab.ui.control.Label
        SavescorematrixtopngButton    matlab.ui.control.Button
        DotMatrixTab                  matlab.ui.container.Tab
        CompareButton                 matlab.ui.control.Button
        SavetopngButton               matlab.ui.control.Button
        WindowEditFieldLabel          matlab.ui.control.Label
        WindowEditField               matlab.ui.control.NumericEditField
        ErrorEditFieldLabel           matlab.ui.control.Label
        ErrorEditField                matlab.ui.control.NumericEditField
        SimilarityLabel               matlab.ui.control.Label
        FilenameEditFieldLabel        matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
        IdEditFieldLabel              matlab.ui.control.Label
        IdEditField                   matlab.ui.control.EditField
        SequenceEditFieldLabel        matlab.ui.control.Label
        SequenceEditField             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        DialogApp   % dialog box app
        selectedCell % selected cell in table with sequences
        FastaList = struct('id',{},'sequence',{}) % struct data displayed in table
        filM        % filtered dot matrix
        Aata1
        data2
        score       % score from align algorithm
        alignment   % alignment of seq from algorithm
        mode        % chosen mode of needleman wunsch algorithm
        indrange    % idexes range for fasta format
    end
    
    methods (Access = public)
        
        function savedotmatrix(app, filename)
            imwrite(1-app.filM,filename);
            app.SavetopngButton.Enable = 'on';
        end
        
        function savealignment(app,filename)
            saveToFile(filename,app.data1.id, app.data2.id,app.mode,app.MatchEditField.Value,app.MismatchEditField.Value,app.GapEditField.Value,app.score,size(app.alignment,2),app.alignment);
            app.SavealignmenttotxtButton.Enable='on';
        end
        
        function savescorematrix(app,filename)
            % define figure and axes to specifications.
            f = figure('Visible', false);
            axNew = axes(f);
            % copy
            myCopyObj(app.UIAxes, axNew);
            % print
            print(f,'-dpng','-r400', filename)
            delete(f);
            app.SavescorematrixtopngButton.Enable='on';
            app.SavescorematrixtopngButtonSW.Enable='on';
        end
        
        
        function savelocalalignment(app,filename)
        
            saveToFastaFormat(filename,app.data1.id,app.data2.id,app.alignment,app.indrange);
            app.SavealignmenttotxtButtonSW.Enable = 'on';
        end
        
        
        function getData(app)
            cla(app.UIAxes,"reset");
            indices = app.selectedCell; % get index of selected cell/cells
            
            if size(indices) == 0 % if nothing selected, random sequence
                app.data1 = struct('id',{'SEQUENCE_ID'},'sequence',{'AACTACTAGCATACTA'});
                app.data2 = app.data1;
            elseif size(indices) >= 2 % if too many selected take first two
                app.data1 = app.FastaList(indices(1));
                app.data2 = app.FastaList(indices(2));
            else
                app.data1 = app.FastaList(indices(1));
                app.data2 = app.data1;
                % if one is selected compare with itself
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.BackgroundColor=[1 1 1];
        end

        % Button pushed function: GetdataButton
        function GetdataButtonPushed(app, event)
            % empty data
            FastaData = struct('id',{},'sequence',{});
            FastaData2 = struct('id',{},'sequence',{});
            FastaData3 = struct('id',{},'sequence',{});
            
            if app.FromfileCheckBox.Value & ~isempty(app.FilenameEditField)
                filename = app.FilenameEditField.Value;
                FastaData = readFromFile(filename);
            end
            
            if app.FromncbibaseCheckBox.Value & ~isempty(app.IdEditField)
                id = app.IdEditField.Value;
                if app.ProteinCheckBox.Value
                    FastaData2 = readFromURL(id,'protein');
                else % by default its nuccore
                    FastaData2 = readFromURL(id,'nuccore');
                end
                
            end
            
            if app.ManuallyCheckBox.Value & ~isempty(app.SequenceEditField)
                FastaData3 = readManuallyEntered(app.SequenceEditField.Value);
            end
            
            app.FastaList = [app.FastaList, FastaData, FastaData2, FastaData3];
            
            
            app.UITable.set('Data',struct2table(app.FastaList));
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            getData(app);
            
            [app.filM,isSwitched] = computeDotMatrix(app.data1.sequence,app.data2.sequence,app.WindowEditField.Value,app.ErrorEditField.Value);
            
            
            % draw filtered dot mat
            I = imagesc(app.filM,'Parent',app.UIAxes);
            app.UIAxes.BackgroundColor=[1 1 1];
            app.UIAxes.XLim = [0.5 I.XData(2)]; % set limits for axes
            app.UIAxes.YLim = [0.5 I.YData(2)];
            app.UIAxes.DataAspectRatio=[1 1 1]; % scaleimage (plot)
            colormap(app.UIAxes,1-gray);
            title(app.UIAxes,'Filtered dot matrix');
            % switch seqs id on plot
            if isSwitched
                xlabel(app.UIAxes,app.data1.id(1:10));
                ylabel(app.UIAxes,app.data2.id(1:10));
            else
                xlabel(app.UIAxes,app.data2.id(1:10));
                ylabel(app.UIAxes,app.data1.id(1:10));
            end
            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selectedCell = event.Indices;
        end

        % Button pushed function: SavetopngButton
        function SavetopngButtonPushed(app, event)
            % disable save button while dialog is open
            app.SavetopngButton.Enable = 'off';
            
            app.DialogApp = dialogApp(app,1); % case 1 symbolize dot mat
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(app.DialogApp)
        end

        % Value changed function: FromfileCheckBox
        function FromfileCheckBoxValueChanged(app, event)
            value = app.FromfileCheckBox.Value;
            if value
                app.FilenameEditField.Enable = 'on';
            else
                app.FilenameEditField.Enable = 'off';
            end
        end

        % Value changed function: FromncbibaseCheckBox
        function FromncbibaseCheckBoxValueChanged(app, event)
            value = app.FromncbibaseCheckBox.Value;
            if value
                app.IdEditField.Enable = 'on';
                app.NucleotideCheckBox.Enable = 'on';
                app.ProteinCheckBox.Enable = 'on';
            else
                app.IdEditField.Enable = 'off';
                app.NucleotideCheckBox.Enable = 'off';
                app.ProteinCheckBox.Enable = 'off';
            end
        end

        % Button pushed function: CleardataButton
        function CleardataButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
            app.UITable.Data={};
            app.selectedCell = [];
            app.ScoreValue.Text=""; app.ScoreValueSW.Text="";
            app.AlignmentResult.Text=""; app.AlignmentResultSW.Text="";
            app.FastaList=struct('id',{},'sequence',{});
        end

        % Value changed function: NucleotideCheckBox
        function NucleotideCheckBoxValueChanged(app, event)
            value = app.NucleotideCheckBox.Value;
            if value
                app.ProteinCheckBox.Value = false;
            else
                app.ProteinCheckBox.Value = true;
            end
        end

        % Value changed function: ProteinCheckBox
        function ProteinCheckBoxValueChanged(app, event)
            value = app.ProteinCheckBox.Value;
            if value
                app.NucleotideCheckBox.Value = false;
            else
                app.NucleotideCheckBox.Value = true;
            end
        end

        % Value changed function: ManuallyCheckBox
        function ManuallyCheckBoxValueChanged(app, event)
            value = app.ManuallyCheckBox.Value;
            if value
                app.SequenceEditField.Enable = 'on';
            else
                app.SequenceEditField.Enable = 'off';
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            getData(app);
            
            if app.DistanceButton.Value
                app.mode='distance';
            else
                app.mode='similarity';
            end
            
            [app.score,app.alignment,scoreMat,path,isSwitched] = needlemanWunsch(app.data1.sequence,app.data2.sequence,app.mode, app.MatchEditField.Value,app.MismatchEditField.Value,app.GapEditField.Value);
            
            % display score
            app.ScoreValue.Text= sprintf('%s%d', 'Score: ', app.score);
            
            
            % display alignment
            % change font to provide alignment oF char vectors in char array
            app.AlignmentResult.FontName='Consolas';
            app.AlignmentResult.Text=cellstr(app.alignment);


            % draw score matrix with path
            I = imagesc(scoreMat,'Parent',app.UIAxes);
            app.UIAxes.BackgroundColor=[1 1 1];
            app.UIAxes.XLim = [0.5 I.XData(2)+0.5]; % set limits for axes
            app.UIAxes.YLim = [0.5 I.YData(2)+0.5];
            app.UIAxes.DataAspectRatio=[1 1 1]; % scaleimage (plot)
            colorbar(app.UIAxes); app.UIAxes.Color
            colormap(app.UIAxes,spring);
            caxis(app.UIAxes,[min(scoreMat(:)) max(scoreMat(:))]);
            title(app.UIAxes,'Global alignment coring space and optimum path');
            hold(app.UIAxes,"on")
            scatter(app.UIAxes,path(:,2),path(:,1),'filled','MarkerFaceColor','k');
            
            % switch seqs id on plot
            if isSwitched
                xlabel(app.UIAxes,app.data1.id(1:10));
                ylabel(app.UIAxes,app.data2.id(1:10));
            else
                xlabel(app.UIAxes,app.data2.id(1:10));
                ylabel(app.UIAxes,app.data1.id(1:10));
            end
            
        end

        % Button pushed function: SavealignmenttotxtButton
        function SavealignmenttotxtButtonPushed(app, event)
            % disable save button while dialog is open
            app.SavealignmenttotxtButton.Enable = 'off';
            
            app.DialogApp = dialogApp(app,2); % case 2 symbolize alignment
        end

        % Button pushed function: SavescorematrixtopngButton
        function SavescorematrixtopngButtonPushed(app, event)
            % disable save button while dialog is open
            app.SavescorematrixtopngButton.Enable = 'off';
            
            app.DialogApp = dialogApp(app,3); % case 3 symbolize score matrix
        end

        % Button pushed function: SubmitButtonSW
        function SubmitButtonSWPushed(app, event)
            getData(app);
            
            scores = [[app.AA.Value,app.CA.Value,app.GA.Value,app.TA.Value];[app.CA.Value,app.CC.Value,app.GC.Value,app.TC.Value];[app.GA.Value,app.GC.Value,app.GG.Value,app.TG.Value];[app.TA.Value,app.TC.Value,app.TG.Value,app.TT.Value]];
            [app.score,app.alignment,scoreMat,path,app.indrange,isSwitched] = smithWaterman(app.data1.sequence,app.data2.sequence,scores,app.GapEditFieldSW.Value);
            
            
            % display score
            app.ScoreValueSW.Text= sprintf('%s%d', 'Score: ', app.score);
            
             % display alignment
            % change font to provide alignment oF char vectors in char array
            app.AlignmentResultSW.FontName='Consolas';
            app.AlignmentResultSW.Text=cellstr(app.alignment);
            
            % draw score matrix with path
            I = imagesc(scoreMat,'Parent',app.UIAxes);
            app.UIAxes.BackgroundColor=[1 1 1];
            app.UIAxes.XLim = [0.5 I.XData(2)+0.5]; % set limits for axes
            app.UIAxes.YLim = [0.5 I.YData(2)+0.5];
            app.UIAxes.DataAspectRatio=[1 1 1]; % scaleimage (plot)
            colorbar(app.UIAxes); app.UIAxes.Color
            colormap(app.UIAxes);
            caxis(app.UIAxes,[min(scoreMat(:)) max(scoreMat(:))]);
            title(app.UIAxes,'Local alignment scoring space and optimum path');
            hold(app.UIAxes,"on")
            scatter(app.UIAxes,path(:,2),path(:,1),'filled','MarkerFaceColor','k');
            
            if isSwitched
                xlabel(app.UIAxes,app.data1.id(1:10));
                ylabel(app.UIAxes,app.data2.id(1:10));
            else
                xlabel(app.UIAxes,app.data2.id(1:10));
                ylabel(app.UIAxes,app.data1.id(1:10));
            end
        end

        % Button pushed function: SavealignmenttotxtButtonSW
        function SavealignmenttotxtButtonSWPushed(app, event)
            app.SavealignmenttotxtButtonSW.Enable = 'off';
            
            app.DialogApp = dialogApp(app,4);
        end

        % Button pushed function: SavescorematrixtopngButtonSW
        function SavescorematrixtopngButtonSWPushed(app, event)
            app.SavescorematrixtopngButtonSW.Enable = 'off'; 
            
            app.DialogApp = dialogApp(app,3); % case 3 symbolize score matrix
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.GetdataButton = uibutton(app.UIFigure, 'push');
            app.GetdataButton.ButtonPushedFcn = createCallbackFcn(app, @GetdataButtonPushed, true);
            app.GetdataButton.Text = 'Get data';
            app.FromncbibaseCheckBox = uicheckbox(app.UIFigure);
            app.FromncbibaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @FromncbibaseCheckBoxValueChanged, true);
            app.FromncbibaseCheckBox.Text = 'From ncbi base';
            app.FromfileCheckBox = uicheckbox(app.UIFigure);
            app.FromfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @FromfileCheckBoxValueChanged, true);
            app.FromfileCheckBox.Text = 'From file';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'id'; 'sequence'};
            app.UITable.RowName = {''};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectseqsfromtabletoanalyzeLabel = uilabel(app.UIFigure);
            app.SelectseqsfromtabletoanalyzeLabel.Text = 'Select seqs from table to analyze';
            app.CleardataButton = uibutton(app.UIFigure, 'push');
            app.CleardataButton.ButtonPushedFcn = createCallbackFcn(app, @CleardataButtonPushed, true);
            app.CleardataButton.Text = 'Clear data';
            app.NucleotideCheckBox = uicheckbox(app.UIFigure);
            app.NucleotideCheckBox.ValueChangedFcn = createCallbackFcn(app, @NucleotideCheckBoxValueChanged, true);
            app.NucleotideCheckBox.Enable = 'off';
            app.NucleotideCheckBox.Text = 'Nucleotide';
            app.ProteinCheckBox = uicheckbox(app.UIFigure);
            app.ProteinCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProteinCheckBoxValueChanged, true);
            app.ProteinCheckBox.Enable = 'off';
            app.ProteinCheckBox.Text = 'Protein';
            app.ManuallyCheckBox = uicheckbox(app.UIFigure);
            app.ManuallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManuallyCheckBoxValueChanged, true);
            app.ManuallyCheckBox.Text = 'Manually';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SmithWatermanTab = uitab(app.TabGroup2);
            app.SmithWatermanTab.Title = 'Smith Waterman';
            app.SubmitButtonSW = uibutton(app.SmithWatermanTab, 'push');
            app.SubmitButtonSW.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonSWPushed, true);
            app.SubmitButtonSW.Text = 'Submit';
            app.GapEditField_2Label = uilabel(app.SmithWatermanTab);
            app.GapEditField_2Label.Text = 'Gap';
            app.GapEditFieldSW = uieditfield(app.SmithWatermanTab, 'numeric');
            app.GapEditFieldSW.Value = -2;
            app.SavealignmenttotxtButtonSW = uibutton(app.SmithWatermanTab, 'push');
            app.SavealignmenttotxtButtonSW.ButtonPushedFcn = createCallbackFcn(app, @SavealignmenttotxtButtonSWPushed, true);
            app.SavealignmenttotxtButtonSW.Text = 'Save alignment to .txt';
            app.ScoreValueSW = uilabel(app.SmithWatermanTab);
            app.ScoreValueSW.Text = '';
            app.AlignmentResultSW = uilabel(app.SmithWatermanTab);
            app.AlignmentResultSW.Text = '';
            app.ACGTTextAreaLabel_2 = uilabel(app.SmithWatermanTab);
            app.ACGTTextAreaLabel_2.Text = 'A';
            app.AEditFieldLabel = uilabel(app.SmithWatermanTab);
            app.AEditFieldLabel.Text = 'A';
            app.AA = uieditfield(app.SmithWatermanTab, 'numeric');
            app.AA.Value = 1;
            app.CEditFieldLabel = uilabel(app.SmithWatermanTab);
            app.CEditFieldLabel.Text = 'C';
            app.CA = uieditfield(app.SmithWatermanTab, 'numeric');
            app.CA.Value = -1;
            app.GEditFieldLabel = uilabel(app.SmithWatermanTab);
            app.GEditFieldLabel.Text = 'G';
            app.GA = uieditfield(app.SmithWatermanTab, 'numeric');
            app.GA.Value = -1;
            app.TEditFieldLabel = uilabel(app.SmithWatermanTab);
            app.TEditFieldLabel.Text = 'T';
            app.TA = uieditfield(app.SmithWatermanTab, 'numeric');
            app.TA.Value = -1;
            app.CC = uieditfield(app.SmithWatermanTab, 'numeric');
            app.CC.Value = 1;
            app.GC = uieditfield(app.SmithWatermanTab, 'numeric');
            app.GC.Value = -1;
            app.TC = uieditfield(app.SmithWatermanTab, 'numeric');
            app.TC.Value = -1;
            app.TG = uieditfield(app.SmithWatermanTab, 'numeric');
            app.TG.Value = -1;
            app.GG = uieditfield(app.SmithWatermanTab, 'numeric');
            app.GG.Value = 1;
            app.TT = uieditfield(app.SmithWatermanTab, 'numeric');
            app.TT.Value = 1;
            app.ACGTTextAreaLabel_3 = uilabel(app.SmithWatermanTab);
            app.ACGTTextAreaLabel_3.Text = 'C';
            app.ACGTTextAreaLabel_4 = uilabel(app.SmithWatermanTab);
            app.ACGTTextAreaLabel_4.Text = 'G';
            app.ACGTTextAreaLabel_5 = uilabel(app.SmithWatermanTab);
            app.ACGTTextAreaLabel_5.Text = 'T';
            app.SavescorematrixtopngButtonSW = uibutton(app.SmithWatermanTab, 'push');
            app.SavescorematrixtopngButtonSW.ButtonPushedFcn = createCallbackFcn(app, @SavescorematrixtopngButtonSWPushed, true);
            app.SavescorematrixtopngButtonSW.Text = 'Save score matrix to .png';
            app.TextArea = uitextarea(app.SmithWatermanTab);
            app.TextArea.Value = {'Please note that this implementation is only for nuccore'};
            app.NeedlemanWunschTab = uitab(app.TabGroup2);
            app.NeedlemanWunschTab.Title = 'Needleman Wunsch ';
            app.ModeButtonGroup = uibuttongroup(app.NeedlemanWunschTab);
            app.ModeButtonGroup.Title = 'Mode';
            app.SimilarityButton = uiradiobutton(app.ModeButtonGroup);
            app.SimilarityButton.Text = 'Similarity';
            app.SimilarityButton.Value = true;
            app.DistanceButton = uiradiobutton(app.ModeButtonGroup);
            app.DistanceButton.Text = 'Distance';
            app.MatchEditFieldLabel = uilabel(app.NeedlemanWunschTab);
            app.MatchEditFieldLabel.Text = 'Match';
            app.MatchEditField = uieditfield(app.NeedlemanWunschTab, 'numeric');
            app.MatchEditField.Value = 1;
            app.MismatchEditFieldLabel = uilabel(app.NeedlemanWunschTab);
            app.MismatchEditFieldLabel.Text = 'Mismatch';
            app.MismatchEditField = uieditfield(app.NeedlemanWunschTab, 'numeric');
            app.MismatchEditField.Value = -1;
            app.GapEditFieldLabel = uilabel(app.NeedlemanWunschTab);
            app.GapEditFieldLabel.Text = 'Gap';
            app.GapEditField = uieditfield(app.NeedlemanWunschTab, 'numeric');
            app.GapEditField.Value = -2;
            app.SubmitButton = uibutton(app.NeedlemanWunschTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.SavealignmenttotxtButton = uibutton(app.NeedlemanWunschTab, 'push');
            app.SavealignmenttotxtButton.ButtonPushedFcn = createCallbackFcn(app, @SavealignmenttotxtButtonPushed, true);
            app.SavealignmenttotxtButton.Text = 'Save alignment to .txt';
            app.AlignmentResult = uilabel(app.NeedlemanWunschTab);
            app.AlignmentResult.Text = '';
            app.ScoreValue = uilabel(app.NeedlemanWunschTab);
            app.ScoreValue.Text = '';
            app.SavescorematrixtopngButton = uibutton(app.NeedlemanWunschTab, 'push');
            app.SavescorematrixtopngButton.ButtonPushedFcn = createCallbackFcn(app, @SavescorematrixtopngButtonPushed, true);
            app.SavescorematrixtopngButton.Text = 'Save score matrix to .png';
            app.DotMatrixTab = uitab(app.TabGroup2);
            app.DotMatrixTab.Title = 'Dot Matrix';
            app.CompareButton = uibutton(app.DotMatrixTab, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.SavetopngButton = uibutton(app.DotMatrixTab, 'push');
            app.SavetopngButton.ButtonPushedFcn = createCallbackFcn(app, @SavetopngButtonPushed, true);
            app.SavetopngButton.Text = 'Save dot matrix to .png';
            app.WindowEditFieldLabel = uilabel(app.DotMatrixTab);
            app.WindowEditFieldLabel.Text = 'Window';
            app.WindowEditField = uieditfield(app.DotMatrixTab, 'numeric');
            app.WindowEditField.Value = 4;
            app.ErrorEditFieldLabel = uilabel(app.DotMatrixTab);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.DotMatrixTab, 'numeric');
            app.ErrorEditField.Value = 1;
            app.SimilarityLabel = uilabel(app.DotMatrixTab);
            app.SimilarityLabel.Text = 'Similarity';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Enable = 'off';
            app.IdEditFieldLabel = uilabel(app.UIFigure);
            app.IdEditFieldLabel.Text = 'Id';
            app.IdEditField = uieditfield(app.UIFigure, 'text');
            app.IdEditField.Enable = 'off';
            app.SequenceEditFieldLabel = uilabel(app.UIFigure);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceEditField = uieditfield(app.UIFigure, 'text');
            app.SequenceEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seqAnalyzerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end